classdef appdraftdone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ZSlider          matlab.ui.control.Slider
        ZPositionLabel   matlab.ui.control.Label
        YSlider          matlab.ui.control.Slider
        YPositioinLabel  matlab.ui.control.Label
        XSlider          matlab.ui.control.Slider
        XPositioinLabel  matlab.ui.control.Label
        Theta3EditField  matlab.ui.control.NumericEditField
        degLabel_3       matlab.ui.control.Label
        Theta2EditField  matlab.ui.control.NumericEditField
        degLabel_2       matlab.ui.control.Label
        Theta1EditField  matlab.ui.control.NumericEditField
        degLabel         matlab.ui.control.Label
        StartButton      matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        base_to_joint_length = 0.15; % Base to Joint
        joint_to_end_effector_length = 0.3; % Joint to end effector
        base_side_length = 645e-3 % Base side length
        end_effector_side_length = 200e-3; % End effector side length
        parameters % Array to store all parameters    
    end
    
    methods (Access = private)
        
        function startupFcn(app)
                % Initialize the robot's parameters
                app.parameters = [app.base_to_joint_length, ...
                app.joint_to_end_effector_length, ...
                app.base_side_length, ...
                app.end_effector_side_length];
        end

        
        function UpdateRobotPosition(app)
            % Get current positions from sliders
            x = app.XSlider.Value;
            y = app.YSlider.Value;
            z = app.ZSlider.Value;
            position = [x, y, z];

            % Calculate inverse kinematics to get joint angles
            theta_values = app.InverseKinematics(position);

            % Update the 3D plot on the app's axes
            app.PlotPosition(position, theta_values);

            % Update theta values in the numeric displays
            app.Theta1EditField.Value = theta_values(1);
            app.Theta2EditField.Value = theta_values(2);
            app.Theta3EditField.Value = theta_values(3);
        end

        
        function [theta_values] = InverseKinematics(app, position)
                x = position(1);
                y = position(2);
                z = position(3);
                theta1 = 0;
                theta2 = 0;
                theta3 = 0;

                [theta1, status] = app.CalcInverse(position);
                if status == 0
                    [theta2, status] = app.CalcInverse([x * cosd(120) + y * sind(120), ...
                        y * cosd(120) - x * sind(120), z]);
                end
    
                if status == 0
                    [theta3, status] = app.CalcInverse([x * cosd(120) - y * sind(120), ...
                        y * cosd(120) + x * sind(120), z]);
                end
    
                if status == 0
                    theta_values = [theta1, theta2, theta3];
                else
                    theta_values = [0, 0, 0];
                end
            end

        
        function [theta, status] = CalcInverse(app, pose)
            x = pose(1);
            y = pose(2);
            z = pose(3);
            theta = 0;

            % Triangular side lengths
            y1 = -app.base_side_length / (2 * sqrt(3));
            k = app.end_effector_side_length / (2 * sqrt(3));
            y = y - k;

            % Inverse kinematics calculation
            a = (x^2 + y^2 + z^2 + app.base_to_joint_length^2 - app.joint_to_end_effector_length^2 - y1^2) / (2 * z);
            b = (y1 - y) / z;

            d = -(a + b * y1)^2 + app.base_to_joint_length * (b^2 * app.base_to_joint_length + app.base_to_joint_length); 
            if d < 0
                disp('Pose not in range. Choose another pose.');
                status = 1;
            else
                yj = (y1 - a * b - sqrt(d)) / (b^2 + 1);
                zj = a + b * yj;
                theta = atan2d(-zj, (y1 - yj));

                if yj > y1
                    theta = theta + 180;
                end
                status = 0;
            end
        end


function PlotPosition(app, position, theta_values)
    % Extract position and angle values
    x = position(1);
    y = position(2);
    z = position(3);
    theta1 = theta_values(1);
    theta2 = theta_values(2);
    theta3 = theta_values(3);

    % Clear the axes before plotting
    cla(app.UIAxes);

    % Plot configuration
    hold(app.UIAxes, 'on');
    grid(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
    xlabel(app.UIAxes, 'X (m)');
    ylabel(app.UIAxes, 'Y (m)');
    zlabel(app.UIAxes, 'Z (m)');
    
    % Adjust the axis limits to ensure visibility of the entire robot
    l = app.base_side_length + 2 * app.base_to_joint_length; 
    axis(app.UIAxes, [-l l -l l -l l]);

    % Define the vertices of the base triangle
    P_base1 = [app.base_side_length / 2, -app.base_side_length / (2 * sqrt(3)), 0];
    P_base2 = [-app.base_side_length / 2, -app.base_side_length / (2 * sqrt(3)), 0];
    P_base3 = [0, app.base_side_length / sqrt(3), 0];

    % Plot the base triangle (blue)
    line(app.UIAxes, [P_base1(1) P_base2(1)], [P_base1(2) P_base2(2)], [P_base1(3) P_base2(3)], 'Color', 'b');
    line(app.UIAxes, [P_base2(1) P_base3(1)], [P_base2(2) P_base3(2)], [P_base2(3) P_base3(3)], 'Color', 'b');
    line(app.UIAxes, [P_base3(1) P_base1(1)], [P_base3(2) P_base1(2)], [P_base3(3) P_base1(3)], 'Color', 'b');

    % Plot the joints of the base at the acute angles
    % These points correspond directly to the vertices of the base triangle
    plot3(app.UIAxes, P_base1(1), P_base1(2), P_base1(3), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'k'); % Joint 1
    plot3(app.UIAxes, P_base2(1), P_base2(2), P_base2(3), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'k'); % Joint 2
    plot3(app.UIAxes, P_base3(1), P_base3(2), P_base3(3), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'k'); % Joint 3

    % Calculate and plot the joint positions based on theta values
    P_Joint1 = P_base1 + [0, -app.base_to_joint_length * cosd(theta1), -app.base_to_joint_length * sind(theta1)];
    P_Joint2 = P_base2 + [0, -app.base_to_joint_length * cosd(theta2), -app.base_to_joint_length * sind(theta2)];
    P_Joint3 = P_base3 + [0, -app.base_to_joint_length * cosd(theta3), -app.base_to_joint_length * sind(theta3)];

    % Base to joint links (green)
    line(app.UIAxes, [P_base1(1) P_Joint1(1)], [P_base1(2) P_Joint1(2)], [P_base1(3) P_Joint1(3)], 'Color', 'g');
    line(app.UIAxes, [P_base2(1) P_Joint2(1)], [P_base2(2) P_Joint2(2)], [P_base2(3) P_Joint2(3)], 'Color', 'g');
    line(app.UIAxes, [P_base3(1) P_Joint3(1)], [P_base3(2) P_Joint3(2)], [P_base3(3) P_Joint3(3)], 'Color', 'g');

    % Plot the end effector
    P_end_effector1 = [x, y, z] + [app.end_effector_side_length / 2, -app.end_effector_side_length / (2 * sqrt(3)), 0];
    P_end_effector2 = [x, y, z] + [-app.end_effector_side_length / 2, -app.end_effector_side_length / (2 * sqrt(3)), 0];
    P_end_effector3 = [x, y, z] + [0, app.end_effector_side_length / sqrt(3), 0];

    % Plot the end-effector triangle (red)
    line(app.UIAxes, [P_end_effector1(1) P_end_effector2(1)], [P_end_effector1(2) P_end_effector2(2)], [P_end_effector1(3) P_end_effector2(3)], 'Color', 'r');
    line(app.UIAxes, [P_end_effector2(1) P_end_effector3(1)], [P_end_effector2(2) P_end_effector3(2)], [P_end_effector2(3) P_end_effector3(3)], 'Color', 'r');
    line(app.UIAxes, [P_end_effector3(1) P_end_effector1(1)], [P_end_effector3(2) P_end_effector1(2)], [P_end_effector3(3) P_end_effector1(3)], 'Color', 'r');

    % Plot the links between joints and end effector
    line(app.UIAxes, [P_Joint1(1) P_end_effector1(1)], [P_Joint1(2) P_end_effector1(2)], [P_Joint1(3) P_end_effector1(3)], 'Color', 'g');
    line(app.UIAxes, [P_Joint2(1) P_end_effector2(1)], [P_Joint2(2) P_end_effector2(2)], [P_Joint2(3) P_end_effector2(3)], 'Color', 'g');
    line(app.UIAxes, [P_Joint3(1) P_end_effector3(1)], [P_Joint3(2) P_end_effector3(2)], [P_Joint3(3) P_end_effector3(3)], 'Color', 'g');

    hold(app.UIAxes, 'off');
end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
                % Get the slider value and ensure it's within the defined limits
                x = event.Value;
                x = min(max(x, app.XSlider.Limits(1)), app.XSlider.Limits(2));
                
                % Update the slider with the clamped value
                app.XSlider.Value = x;
                
                % Call the update function to adjust the robot's position
                app.UpdateRobotPosition();
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
                % Get the slider value and ensure it's within the defined limits
                    y = event.Value;
                    y = min(max(y, app.YSlider.Limits(1)), app.YSlider.Limits(2));
                    
                    % Update the slider with the clamped value
                    app.YSlider.Value = y;
                    
                    % Call the update function to adjust the robot's position
                    app.UpdateRobotPosition();
            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
                % Get the slider value and ensure it's within the defined limits
                    z = event.Value;
                    z = min(max(z, app.ZSlider.Limits(1)), app.ZSlider.Limits(2));
                    
                    % Update the slider with the clamped value
                    app.ZSlider.Value = z;
                    
                    % Call the update function to adjust the robot's position
                    app.UpdateRobotPosition();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                % Get the current values from the sliders
    x = app.XSlider.Value;
    y = app.YSlider.Value;
    z = app.ZSlider.Value;
    position = [x, y, z];
    
    % Calculate inverse kinematics to get joint angles
    theta_values = InverseKinematics(app, position);
    
    % Update theta values in the numeric displays
    app.Theta1EditField.Value = theta_values(1);
    app.Theta2EditField.Value = theta_values(2);
    app.Theta3EditField.Value = theta_values(3);
    
    % Plot the Delta robot in the 3D Axes component
    PlotPosition(app, position, theta_values);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'θ1 (deg)';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'θ2 (deg)';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = 'θ3 (deg)';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.XPositioinLabel = uilabel(app.UIFigure);
            app.XPositioinLabel.Text = 'X Position';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-0.15 0.15];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YPositioinLabel = uilabel(app.UIFigure);
            app.YPositioinLabel.Text = 'Y Position';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-0.15 0.15];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZPositionLabel = uilabel(app.UIFigure);
            app.ZPositionLabel.Text = 'Z Position';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-0.4 -0.1];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.Value = -0.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appdraftdone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end